classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        startButton        matlab.ui.control.StateButton
        numEditFieldLabel  matlab.ui.control.Label
        numEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        num % Description
        speech % Description
        speechIn % Description
        ncoeff = 12;          %MFCC参数阶数
        N = 10;               %10个数字
        fs=16000;             %采样频率
        duration2 = 2;        %录音时长
        k = 3;                %训练样本的人数
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            value = app.startButton.Value;
            app.speech = audiorecorder(app.fs,16,1);
            recordblocking(app.speech,app.duration2);
            app.speechIn=getaudiodata(app.speech);
            app.num = process(app.speechIn);
            app.numEditField.Value = app.num;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.startButton = uibutton(app.UIFigure, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = 'start';
            app.numEditFieldLabel = uilabel(app.UIFigure);
            app.numEditFieldLabel.Text = 'num';
            app.numEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end